classdef dontknowDH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumberofJointsEditFieldLabel    matlab.ui.control.Label
        NumberofJointsEditField         matlab.ui.control.NumericEditField
        ManipulatorConfigEditFieldLabel  matlab.ui.control.Label
        ManipulatorConfigEditField      matlab.ui.control.EditField
        ZaxisEditFieldLabel             matlab.ui.control.Label
        ZaxisEditField                  matlab.ui.control.EditField
        LinkLenghtsEditFieldLabel       matlab.ui.control.Label
        LinkLenghtsEditField            matlab.ui.control.EditField
        MassesoflinksEditFieldLabel     matlab.ui.control.Label
        MassesoflinksEditField          matlab.ui.control.EditField
        GearRatiosEditFieldLabel        matlab.ui.control.Label
        GearRatiosEditField             matlab.ui.control.EditField
        LimitsEditFieldLabel            matlab.ui.control.Label
        LimitsEditField                 matlab.ui.control.EditField
        DirectionofGravityEditFieldLabel  matlab.ui.control.Label
        DirectionofGravityEditField     matlab.ui.control.EditField
        InertiasofMotorsEditFieldLabel  matlab.ui.control.Label
        InertiasofMotorsEditField       matlab.ui.control.EditField
        CalculateDHButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateDHButton
        function CalculateDHButtonPushed(app, event)
            dof = app.NumberofJointsEditField.Value
            jtype = app.ManipulatorConfigEditField.Value 
            z = str2num(app.ZaxisEditField.Value)
            z = transpose(z); 
            linklen = str2num(app.LinkLenghtsEditField.Value) 


            dh = calcDH(dof, jtype, z, linklen)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NumberofJointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofJointsEditFieldLabel.Text = 'Number of Joints';
            app.NumberofJointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ManipulatorConfigEditFieldLabel = uilabel(app.UIFigure);
            app.ManipulatorConfigEditFieldLabel.Text = 'Manipulator Config';
            app.ManipulatorConfigEditField = uieditfield(app.UIFigure, 'text');
            app.CalculateDHButton = uibutton(app.UIFigure, 'push');
            app.CalculateDHButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDHButtonPushed, true);
            app.CalculateDHButton.Text = 'Calculate DH';
            app.LinkLenghtsEditFieldLabel = uilabel(app.UIFigure);
            app.LinkLenghtsEditFieldLabel.Text = 'Link Lenghts';
            app.LinkLenghtsEditField = uieditfield(app.UIFigure, 'text');
            app.ZaxisEditFieldLabel = uilabel(app.UIFigure);
            app.ZaxisEditFieldLabel.Text = 'Z-axis';
            app.ZaxisEditField = uieditfield(app.UIFigure, 'text');
            app.MassesoflinksEditFieldLabel = uilabel(app.UIFigure);
            app.MassesoflinksEditFieldLabel.Text = 'Masses of links';
            app.MassesoflinksEditField = uieditfield(app.UIFigure, 'text');
            app.GearRatiosEditFieldLabel = uilabel(app.UIFigure);
            app.GearRatiosEditFieldLabel.Text = 'Gear Ratios';
            app.GearRatiosEditField = uieditfield(app.UIFigure, 'text');
            app.LimitsEditFieldLabel = uilabel(app.UIFigure);
            app.LimitsEditFieldLabel.Text = 'Limits';
            app.LimitsEditField = uieditfield(app.UIFigure, 'text');
            app.DirectionofGravityEditFieldLabel = uilabel(app.UIFigure);
            app.DirectionofGravityEditFieldLabel.Text = 'Direction of Gravity';
            app.DirectionofGravityEditField = uieditfield(app.UIFigure, 'text');
            app.InertiasofMotorsEditFieldLabel = uilabel(app.UIFigure);
            app.InertiasofMotorsEditFieldLabel.Text = 'Inertias of Motors';
            app.InertiasofMotorsEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dontknowDH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end